classdef newton_raphson_app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        answer                     matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        sym_dfunc                  matlab.ui.control.Label
        sym_func                   matlab.ui.control.Label
        error_msg                  matlab.ui.control.Label
        closeButton                matlab.ui.control.Button
        resetButton                matlab.ui.control.Button
        executeButton              matlab.ui.control.Button
        equation                   matlab.ui.control.EditField
        equationLabel              matlab.ui.control.Label
        maxnumberiterations        matlab.ui.control.NumericEditField
        maxnumberiterationsLabel   matlab.ui.control.Label
        errortolerance             matlab.ui.control.NumericEditField
        errortoleranceLabel        matlab.ui.control.Label
        initialguess               matlab.ui.control.NumericEditField
        initialguessLabel          matlab.ui.control.Label
        OutputsLabel               matlab.ui.control.Label
        InputsLabel                matlab.ui.control.Label
        NewtonRaphsonMethondLabel  matlab.ui.control.Label
        UIAxes_convergence         matlab.ui.control.UIAxes
        UIAxes_func                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            p0 = app.initialguess.Value;
            tol = app.errortolerance.Value;
            max_iter = app.maxnumberiterations.Value;
            func = str2func(app.equation.Value);

            % start of the function
            % symbolic derivatives

            syms x
            func_sym = func(x);
            dfunc_sym = diff(func_sym, x);

            % derivative function

            dfunc = matlabFunction(dfunc_sym, "Vars", x);

            % step 1
            iter = 1;

            results = table();

            % step 2
            while iter <= max_iter
                % step 3
                f_p0 = func(p0);
                df_p0 = dfunc(p0);
                p = p0 - f_p0./df_p0;

                f_p = func(p);  % not necessary
                results(end+1,:) = table( ...
                    iter, p0, p, f_p0, df_p0, f_p ...
                    );
                % convergence flag
                converged = false;

                % step 4
                if abs(p - p0) < tol
                    converged = true;
                    msg = sprintf("the method converged successfully");
                    break
                end

                % step 5
                iter = iter + 1;

                % step 6
                p0 = p;

            end
            % end of the function

            if ~converged
                msg = sprintf("the method failed after %d iteration", max_iter);
            end

            %
            app.error_msg.Text = msg;
            app.answer.Text = sprintf("root = %f; at iteration = %d", p, iter);
            app.sym_func.Interpreter = 'latex';
            app.sym_func.Text = ['$f(x) = ' latex(func_sym) '$'];
            app.sym_dfunc.Interpreter = 'latex';
            app.sym_dfunc.Text = ['$f''(x) = ' latex(dfunc_sym) '$'];

            app.UITable.Data = results;
            app.UITable.ColumnName = [...
                "iter", "p0", "p", "f_p0", "df_p0", "f_p"...
            ];
            fplot(app.UIAxes_func, func)
            yline(app.UIAxes_func, 0, ':')
            plot(app.UIAxes_convergence, results.iter, abs(results.p - results.p0))
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.initialguess.Value = 1;
            app.errortolerance.Value = 1e-5;
            app.maxnumberiterations.Value = 100;
            app.equation.Value = "@(x) x.^2 - 4.*x + 4 - log(x)";

            app.error_msg.Text = "";
            app.answer.Text = "";
            app.sym_func.Text = "";
            app.sym_dfunc.Text = "";

            app.UITable.Data = [];
            app.UITable.ColumnName = [];
            cla(app.UIAxes_func)
            cla(app.UIAxes_convergence)
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_func = uiaxes(app.UIFigure);
            title(app.UIAxes_func, 'Title')
            xlabel(app.UIAxes_func, 'X')
            ylabel(app.UIAxes_func, 'Y')
            zlabel(app.UIAxes_func, 'Z')
            app.UIAxes_convergence = uiaxes(app.UIFigure);
            title(app.UIAxes_convergence, 'Title')
            xlabel(app.UIAxes_convergence, 'X')
            ylabel(app.UIAxes_convergence, 'Y')
            zlabel(app.UIAxes_convergence, 'Z')
            app.NewtonRaphsonMethondLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonMethondLabel.Text = 'Newton Raphson Methond';
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.initialguessLabel = uilabel(app.UIFigure);
            app.initialguessLabel.Text = 'initial guess';
            app.initialguess = uieditfield(app.UIFigure, 'numeric');
            app.initialguess.Value = 1;
            app.errortoleranceLabel = uilabel(app.UIFigure);
            app.errortoleranceLabel.Text = 'error tolerance';
            app.errortolerance = uieditfield(app.UIFigure, 'numeric');
            app.errortolerance.Placeholder = '1e-10';
            app.errortolerance.Value = 1e-05;
            app.maxnumberiterationsLabel = uilabel(app.UIFigure);
            app.maxnumberiterationsLabel.Text = 'max number iterations';
            app.maxnumberiterations = uieditfield(app.UIFigure, 'numeric');
            app.maxnumberiterations.Placeholder = '100';
            app.maxnumberiterations.Value = 100;
            app.equationLabel = uilabel(app.UIFigure);
            app.equationLabel.Text = 'equation';
            app.equation = uieditfield(app.UIFigure, 'text');
            app.equation.Placeholder = '@(x) x.^2 - 4.*x + 4 - log(x)';
            app.equation.Value = '@(x) x.^2 - 4.*x + 4 - log(x)';
            app.executeButton = uibutton(app.UIFigure, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'execute';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.error_msg = uilabel(app.UIFigure);
            app.error_msg.Text = '';
            app.sym_func = uilabel(app.UIFigure);
            app.sym_func.Text = '';
            app.sym_dfunc = uilabel(app.UIFigure);
            app.sym_dfunc.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.answer = uilabel(app.UIFigure);
            app.answer.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newton_raphson_app_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end