classdef runge_kutta_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        closeButton          matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        resetButton          matlab.ui.control.Button
        executeButton        matlab.ui.control.Button
        distcondition        matlab.ui.control.NumericEditField
        distconditionLabel   matlab.ui.control.Label
        timecondition        matlab.ui.control.NumericEditField
        timeconditionLabel   matlab.ui.control.Label
        endtime              matlab.ui.control.NumericEditField
        endtimeLabel         matlab.ui.control.Label
        initialtime          matlab.ui.control.NumericEditField
        initialtimeLabel     matlab.ui.control.Label
        stepsize             matlab.ui.control.NumericEditField
        stepsizeLabel        matlab.ui.control.Label
        functionhandle       matlab.ui.control.EditField
        functionhandleLabel  matlab.ui.control.Label
        InputsLabel          matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            func = str2func(app.functionhandle.Value);

            h = app.stepsize.Value;
            t = app.initialtime.Value:h:app.endtime.Value;

            % t0 = app.timecondition.Value;
            y0 = app.distcondition.Value;

            y = zeros(size(t));
            y(1) = y0;

            results = table();

            for iter = 1:length(t)-1
                t0 = t(iter);
                y0 = y(iter);

                k1 = func(t0, y0);
                k2 = func(t0 + h/2, y0 + h*k1/2);
                k3 = func(t0 + h/2, y0 + h*k2/2);
                k4 = func(t0 + h, y0 + h*k3);
                y1 = y0 + h*(k1 + 2*k2 + 2*k3 + k4)/6;

                y(iter+1) = y1;

                results(end+1,:) = table( ...
                    iter, t0, y0, y1, k1, k2, k3, k4 ...
                    );
            end

            app.UITable.Data = results;
            app.UITable.ColumnName = [...
                "iter", "t0", "y0", "y1", "k1", "k2", "k3", "k4"...
            ];
            plot(app.UIAxes, results.t0, results.y0)
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.functionhandle.Value = '@(t,y) t+y';
            app.stepsize.Value = 0.1;
            app.initialtime.Value = 0;
            app.endtime.Value = 0.2;
            app.timecondition.Value = 0;
            app.distcondition.Value = 1;
            app.UITable.Data = [];
            cla(app.UIAxes);
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'dy/dt')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'y (m)')
            zlabel(app.UIAxes, 'Z')
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.functionhandleLabel = uilabel(app.UIFigure);
            app.functionhandleLabel.Text = 'function handle';
            app.functionhandle = uieditfield(app.UIFigure, 'text');
            app.functionhandle.Placeholder = '@(t,y) t + y';
            app.functionhandle.Value = '@(t,y) t + y';
            app.stepsizeLabel = uilabel(app.UIFigure);
            app.stepsizeLabel.Text = 'step size (h)';
            app.stepsize = uieditfield(app.UIFigure, 'numeric');
            app.stepsize.Placeholder = '0.1';
            app.stepsize.Value = 0.1;
            app.initialtimeLabel = uilabel(app.UIFigure);
            app.initialtimeLabel.Text = 'initial time';
            app.initialtime = uieditfield(app.UIFigure, 'numeric');
            app.initialtime.Placeholder = '0';
            app.endtimeLabel = uilabel(app.UIFigure);
            app.endtimeLabel.Text = 'end time';
            app.endtime = uieditfield(app.UIFigure, 'numeric');
            app.endtime.Placeholder = '0.2';
            app.endtime.Value = 10;
            app.timeconditionLabel = uilabel(app.UIFigure);
            app.timeconditionLabel.Text = 't =';
            app.timecondition = uieditfield(app.UIFigure, 'numeric');
            app.timecondition.Placeholder = '0';
            app.distconditionLabel = uilabel(app.UIFigure);
            app.distconditionLabel.Text = 'y(t) =';
            app.distcondition = uieditfield(app.UIFigure, 'numeric');
            app.distcondition.Placeholder = '1';
            app.distcondition.Value = 1;
            app.executeButton = uibutton(app.UIFigure, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'execute';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runge_kutta_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end